classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        config_Option2Grid              matlab.ui.container.GridLayout
        config_SearchModeLabel          matlab.ui.control.Label
        config_SearchModePanel          matlab.ui.container.ButtonGroup
        config_SearchModeListOfWords    matlab.ui.control.RadioButton
        config_SearchModeTokenSuggestion  matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        config_SelectedTableColumns     matlab.ui.container.CheckBoxTree
        config_SelectedTableColumnsLabel  matlab.ui.control.Label
        config_MiscelaneousPanel2       matlab.ui.container.Panel
        config_MiscelaneousGrid2        matlab.ui.container.GridLayout
        config_WordCloudColumn          matlab.ui.control.DropDown
        config_WordCloudColumnLabel     matlab.ui.control.Label
        config_WordCloudAlgorithm       matlab.ui.control.DropDown
        config_WordCloudAlgorithmLabel  matlab.ui.control.Label
        config_MiscelaneousLabel2       matlab.ui.control.Label
        config_MiscelaneousPanel1       matlab.ui.container.Panel
        config_MiscelaneousGrid1        matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nMinCharacters           matlab.ui.control.Spinner
        config_nMinCharactersLabel      matlab.ui.control.Label
        config_MiscelaneousLabel1       matlab.ui.control.Label
        Folders_Grid                    matlab.ui.container.GridLayout
        FolderMapPanel                  matlab.ui.container.Panel
        FolderMapGrid                   matlab.ui.container.GridLayout
        userPathButton                  matlab.ui.control.Image
        userPath                        matlab.ui.control.EditField
        userPathLabel                   matlab.ui.control.Label
        DataHubPOSTButton               matlab.ui.control.Image
        DataHubPOST                     matlab.ui.control.EditField
        DataHubPOSTLabel                matlab.ui.control.Label
        DataHubGETButton                matlab.ui.control.Image
        DataHubGET                      matlab.ui.control.EditField
        DataHubGETLabel                 matlab.ui.control.Label
        config_FolderMapLabel           matlab.ui.control.Label
        General_Grid                    matlab.ui.container.GridLayout
        openAuxiliarApp2Debug           matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked         matlab.ui.control.CheckBox
        gpuType                         matlab.ui.control.DropDown
        gpuTypeLabel                    matlab.ui.control.Label
        versionInfo                     matlab.ui.control.Label
        versionInfoLabel                matlab.ui.control.Label
        LeftPanelRadioGroup             matlab.ui.container.ButtonGroup
        btnFolder                       matlab.ui.control.RadioButton
        btnAnalysis                     matlab.ui.control.RadioButton
        btnGeneral                      matlab.ui.control.RadioButton
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        menu_ButtonGrid                 matlab.ui.container.GridLayout
        menu_ButtonIcon                 matlab.ui.control.Image
        menu_ButtonLabel                matlab.ui.control.Label
        toolGrid                        matlab.ui.container.GridLayout
        config_ExportTable              matlab.ui.control.Image
        openDevTools                    matlab.ui.control.Image
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        mainApp
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        stableVersion
    end


    methods
        %-----------------------------------------------------------------%
        % IPC: COMUNICAÇÃO ENTRE PROCESSOS
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            elToModify = {app.versionInfo};
            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
            if ~isempty(elDataTag)
                appName = class(app);
                ui.TextView.startup(app.jsBackDoor, elToModify{1}, appName);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app)

            startup_GUIComponents(app)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            switch app.mainApp.executionMode
                case 'webApp'
                    delete(app.openDevTools)
                otherwise
                    app.btnFolder.Enable               = 1;
                    app.gpuType.Enable                 = 1;
                    app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end

            General_updatePanel(app)
            Analysis_updatePanel(app)
            Folder_updatePanel(app)
        end

        %-----------------------------------------------------------------%
        function General_updatePanel(app)
            % Versão:
            ui.TextView.update(app.versionInfo, util.HtmlTextGenerator.AppInfo(app.mainApp.General, app.rootFolder, app.mainApp.executionMode, app.mainApp.rawDataTable, app.mainApp.releasedData, app.mainApp.cacheData, app.mainApp.annotationTable));

            % Renderizador:
            graphRender = opengl('data');
            switch graphRender.HardwareSupportLevel
                case 'basic'; graphRenderSupport = 'hardwarebasic';
                case 'full';  graphRenderSupport = 'hardware';
                case 'none';  graphRenderSupport = 'software';
                otherwise;    graphRenderSupport = graphRender.HardwareSupportLevel; % "driverissue"
            end

            if ~ismember(graphRenderSupport, app.gpuType.Items)
                app.gpuType.Items{end+1} = graphRenderSupport;
            end
            app.gpuType.Value = graphRenderSupport;

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value   = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value     = app.mainApp.General.operationMode.Debug;
        end

        %-----------------------------------------------------------------%
        function Analysis_updatePanel(app)
            % MODO
            switch app.mainApp.General.search.mode
                case 'tokens'
                    app.config_SearchModeTokenSuggestion.Value = 1;
                otherwise
                    app.config_SearchModeListOfWords.Value     = 1;
            end

            % ALGORITMO SUGESTÃO DE TOKENS
            app.config_nMinCharacters.Value     = app.mainApp.General.search.minCharacters;

            numDisplayedTokens = num2str(app.mainApp.General.search.minDisplayedTokens);
            if ismember(numDisplayedTokens, app.config_nMinWords.Items)
                app.config_nMinWords.Value      = numDisplayedTokens;
            end

            % ANOTAÇÃO DO TIPO "WORDCLOUD"
            app.config_WordCloudAlgorithm.Value = app.mainApp.General.search.wordCloud.algorithm;
            app.config_WordCloudColumn.Value    = app.mainApp.General.search.wordCloud.column;

            % VISIBILIDADE DE COLUNAS
            allColumns      = search_Table_ColumnInfo(app, 'allColumns');
            staticColumns   = search_Table_ColumnInfo(app, 'staticColumns');

            [~, sortIndex]  = sort(lower(allColumns));
            GUIAllColumns   = allColumns(sortIndex);

            listOfTreeNodes = cellfun(@(x) uitreenode(app.config_SelectedTableColumns, 'Text', x), GUIAllColumns);
            isStaticColumn  = ismember(GUIAllColumns, staticColumns);

            app.config_SelectedTableColumns.CheckedNodes = listOfTreeNodes(isStaticColumn);
            addStyle(app.config_SelectedTableColumns, class.Constants.configStyle5, 'node', listOfTreeNodes(isStaticColumn))
        end

            %-----------------------------------------------------------------%
        function [columnNames, columnWidth] = search_Table_ColumnNames(app)
            checkedNodes = search_Table_ColumnInfo(app, 'visibleColumns');
            staticColums = search_Table_ColumnInfo(app, 'staticColumns');
            columnNames  = unique([staticColums; checkedNodes], 'stable');

            allColumns   = search_Table_ColumnInfo(app, 'allColumns');
            widthColumns = search_Table_ColumnInfo(app, 'allColumnsWidths');

            columnWidth  = {};
            for ii = 1:numel(columnNames)
                columnName      = columnNames{ii};
                columnIndex     = find(strcmp(allColumns, columnName), 1);

                columnWidth{ii} = widthColumns{columnIndex};
            end
        end

        %-----------------------------------------------------------------%
        function Folder_updatePanel(app)
            % Na versão webapp, a configuração das pastas não é habilitada.

            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.btnFolder.Enable      = 1;

                DataHub_GET  = app.mainApp.General.fileFolder.DataHub_GET;
                if isfolder(DataHub_GET)
                    app.DataHubGET.Value  = DataHub_GET;
                end

                DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
                if isfolder(DataHub_POST)
                    app.DataHubPOST.Value = DataHub_POST;
                end

                app.userPath.Value        = app.mainApp.General.fileFolder.userPath;
            end
        end

        %-----------------------------------------------------------------%
        function columnInfo = search_Table_ColumnInfo(app, type)
            switch type
                case 'staticColumns'
                    staticLogical  = logical(app.mainApp.General.ui.SCHData.columnPosition);
                    staticIndex    = app.mainApp.General.ui.SCHData.columnPosition(staticLogical);
                    [~, idxOrder]  = sort(staticIndex);
                    columnList     = app.mainApp.General.ui.SCHData.name(staticLogical);
                    columnInfo     = columnList(idxOrder);

                case 'visibleColumns'
                    visibleLogical = logical(app.mainApp.General.ui.SCHData.visible);
                    columnInfo     = app.mainApp.General.ui.SCHData.name(visibleLogical);

                case 'allColumns'
                    columnInfo     = app.mainApp.General.ui.SCHData.name;

                case 'allColumnsWidths'
                    columnInfo     = app.mainApp.General.ui.SCHData.columnWidth;
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações do arquivo "GeneralSettings.json".
            app.mainApp    = mainApp;
            app.rootFolder = mainApp.rootFolder;

            if app.isDocked
                app.GridLayout.Padding(4) = 19;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Selection changed function: LeftPanelRadioGroup
        function RadioButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.LeftPanelRadioGroup.SelectedObject;
            switch selectedButton
                case app.btnGeneral;  app.GridLayout.ColumnWidth(4:6) = {'1x',0,0};
                case app.btnAnalysis; app.GridLayout.ColumnWidth(4:6) = {0,'1x',0};
                case app.btnFolder;   app.GridLayout.ColumnWidth(4:6) = {0,0,'1x'};
            end
            
        end

        % Value changed function: gpuType, openAuxiliarApp2Debug, 
        % ...and 1 other component
        function Graphics_ParameterValueChanged(app, event)
            
            switch event.Source
                case app.gpuType
                    if ismember(app.gpuType.Value, {'software', 'hardware', 'hardwarebasic'})
                        eval(sprintf('opengl %s', app.gpuType.Value))

                        graphRender = opengl('data');
                        
                        app.mainApp.General.openGL = app.gpuType.Value;
                        app.mainApp.General.AppVersion.openGL = rmfield(graphRender, {'MaxTextureSize', 'Visual', 'SupportsGraphicsSmoothing', 'SupportsDepthPeelTransparency', 'SupportsAlignVertexCenters', 'Extensions', 'MaxFrameBufferSize'});
                    end

                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.openGL        = app.mainApp.General.openGL;
            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;

            saveGeneralSettings(app)

        end

        % Image clicked function: DataHubGETButton, DataHubPOSTButton, 
        % ...and 1 other component
        function Folder_ButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.DataHubGETButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_GET, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.sch_get', {selectedFolderFiles.name})
                                appUtil.modalWindow(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - GET", do SCH.');
                                return
                            end

                            app.DataHubGET.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_GET = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.sch_post', {selectedFolderFiles.name})
                                appUtil.modalWindow(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do SCH.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                Folder_updatePanel(app)
            end

        end

        % Image clicked function: openDevTools
        function openDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Callback function: config_SelectedTableColumns
        function config_SelectedTableColumnsCheckedNodesChanged(app, event)

            if ~isempty(app.config_SelectedTableColumns.CheckedNodes)
                checkedColumns = {app.config_SelectedTableColumns.CheckedNodes.Text}';
            else
                checkedColumns = {};
            end

            staticColumns  = search_Table_ColumnInfo(app, 'staticColumns');
            staticColumns(ismember(staticColumns, checkedColumns)) = [];
            
            if ~isempty(staticColumns)
                for ii = 1:numel(staticColumns)
                    staticColumnName = staticColumns{ii};
                    staticTreeNodes  = findobj(app.config_SelectedTableColumns, 'Text', staticColumnName);
                    
                    app.config_SelectedTableColumns.CheckedNodes = [app.config_SelectedTableColumns.CheckedNodes; staticTreeNodes];
                end
            end
            [columnNames, columnWidth] = search_Table_ColumnNames(app);
            set(app.search_Table, 'ColumnName', upper(columnNames), 'ColumnWidth', columnWidth)

            if ~isempty(app.search_Table.Data)
                if (numel(columnNames) ~= width(app.search_Table.Data)) || any(~ismember(app.search_Table.ColumnName, upper(columnNames)))
                    secundaryIndex = app.search_Table.UserData.secundaryIndex;
                    app.search_Table.Data = app.rawDataTable(secundaryIndex, columnNames);
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = 'icon_32.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.openDevTools = uiimage(app.toolGrid);
            app.openDevTools.ImageClickedFcn = createCallbackFcn(app, @openDevToolsClicked, true);
            app.openDevTools.Tooltip = {'DevTools'};
            app.openDevTools.ImageSource = 'Debug_18.png';
            app.config_ExportTable = uiimage(app.toolGrid);
            app.config_ExportTable.Enable = 'off';
            app.config_ExportTable.Visible = 'off';
            app.config_ExportTable.Tooltip = {'Abre cópia de base dados no Excel'};
            app.config_ExportTable.ImageSource = 'Sheet_32.png';
            app.menu_ButtonGrid = uigridlayout(app.GridLayout);
            app.menu_ButtonLabel = uilabel(app.menu_ButtonGrid);
            app.menu_ButtonLabel.Text = 'CONFIGURAÇÕES';
            app.menu_ButtonIcon = uiimage(app.menu_ButtonGrid);
            app.menu_ButtonIcon.Tag = '1';
            app.menu_ButtonIcon.ImageSource = 'Settings_18.png';
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.LeftPanelRadioGroup = uibuttongroup(app.GridLayout);
            app.LeftPanelRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioButtonGroupSelectionChanged, true);
            app.btnGeneral = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnGeneral.Text = 'Aspectos gerais';
            app.btnGeneral.Value = true;
            app.btnAnalysis = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnAnalysis.Text = 'Análise';
            app.btnFolder = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFolder.Enable = 'off';
            app.btnFolder.Text = 'Mapeamento de pastas';
            app.General_Grid = uigridlayout(app.GridLayout);
            app.versionInfoLabel = uilabel(app.General_Grid);
            app.versionInfoLabel.Text = 'ASPECTOS GERAIS';
            app.versionInfo = uilabel(app.General_Grid);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.gpuTypeLabel = uilabel(app.General_Grid);
            app.gpuTypeLabel.Text = 'Unidade gráfica:';
            app.gpuType = uidropdown(app.General_Grid);
            app.gpuType.Items = {'hardwarebasic', 'hardware', 'software'};
            app.gpuType.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.gpuType.Enable = 'off';
            app.gpuType.Value = 'hardware';
            app.openAuxiliarAppAsDocked = uicheckbox(app.General_Grid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.General_Grid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.Folders_Grid = uigridlayout(app.GridLayout);
            app.config_FolderMapLabel = uilabel(app.Folders_Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.FolderMapPanel = uipanel(app.Folders_Grid);
            app.FolderMapGrid = uigridlayout(app.FolderMapPanel);
            app.DataHubGETLabel = uilabel(app.FolderMapGrid);
            app.DataHubGETLabel.Text = 'DataHub - GET:';
            app.DataHubGET = uieditfield(app.FolderMapGrid, 'text');
            app.DataHubGET.Editable = 'off';
            app.DataHubGETButton = uiimage(app.FolderMapGrid);
            app.DataHubGETButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.DataHubGETButton.Tag = 'DataHub_GET';
            app.DataHubGETButton.ImageSource = 'OpenFile_36x36.png';
            app.DataHubPOSTLabel = uilabel(app.FolderMapGrid);
            app.DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.DataHubPOST = uieditfield(app.FolderMapGrid, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.FolderMapGrid);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.FolderMapGrid);
            app.userPathLabel.Text = 'Pasta do usuário:';
            app.userPath = uieditfield(app.FolderMapGrid, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.FolderMapGrid);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            app.config_Option2Grid = uigridlayout(app.GridLayout);
            app.config_MiscelaneousLabel1 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel1.Text = 'ALGORITMO SUGESTÃO DE TOKENS';
            app.config_MiscelaneousPanel1 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid1 = uigridlayout(app.config_MiscelaneousPanel1);
            app.config_nMinCharactersLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinCharactersLabel.Text = 'Qtd. mínima caracteres:';
            app.config_nMinCharacters = uispinner(app.config_MiscelaneousGrid1);
            app.config_nMinCharacters.Limits = [2 4];
            app.config_nMinCharacters.RoundFractionalValues = 'on';
            app.config_nMinCharacters.ValueDisplayFormat = '%d';
            app.config_nMinCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_MiscelaneousGrid1);
            app.config_nMinWordsLabel.Interpreter = 'html';
            app.config_nMinWordsLabel.Text = 'Qtd. mínima <i>tokens</i>:';
            app.config_nMinWords = uidropdown(app.config_MiscelaneousGrid1);
            app.config_nMinWords.Items = {'20', '50', '100'};
            app.config_nMinWords.Value = '20';
            app.config_MiscelaneousLabel2 = uilabel(app.config_Option2Grid);
            app.config_MiscelaneousLabel2.Text = 'ANOTAÇÃO DO TIPO "WORDCLOUD"';
            app.config_MiscelaneousPanel2 = uipanel(app.config_Option2Grid);
            app.config_MiscelaneousGrid2 = uigridlayout(app.config_MiscelaneousPanel2);
            app.config_WordCloudAlgorithmLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithmLabel.Text = 'Algoritmo gráfico:';
            app.config_WordCloudAlgorithm = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudAlgorithm.Items = {'D3.js', 'MATLAB built-in'};
            app.config_WordCloudAlgorithm.Value = 'D3.js';
            app.config_WordCloudColumnLabel = uilabel(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumnLabel.Text = 'Pesquisa orientada à coluna:';
            app.config_WordCloudColumn = uidropdown(app.config_MiscelaneousGrid2);
            app.config_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.config_WordCloudColumn.Value = 'Modelo';
            app.config_SelectedTableColumnsLabel = uilabel(app.config_Option2Grid);
            app.config_SelectedTableColumnsLabel.Text = 'VISIBILIDADE DE COLUNAS';
            app.config_SelectedTableColumns = uitree(app.config_Option2Grid, 'checkbox');
            % Assign Checked Nodes
            app.config_SelectedTableColumns.CheckedNodesChangedFcn = createCallbackFcn(app, @config_SelectedTableColumnsCheckedNodesChanged, true);
            app.Image2 = uiimage(app.config_Option2Grid);
            app.Image2.ImageSource = 'Refresh_18.png';
            app.config_SearchModePanel = uibuttongroup(app.config_Option2Grid);
            app.config_SearchModeTokenSuggestion = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeTokenSuggestion.Tag = 'strcmp';
            app.config_SearchModeTokenSuggestion.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à palavra que está sendo escrita, sugerindo <i>tokens</i> relacionados.</p>';
            app.config_SearchModeTokenSuggestion.Interpreter = 'html';
            app.config_SearchModeTokenSuggestion.Value = true;
            app.config_SearchModeListOfWords = uiradiobutton(app.config_SearchModePanel);
            app.config_SearchModeListOfWords.Tag = 'contains';
            app.config_SearchModeListOfWords.Text = '<p style="text-align:justify; line-height:1.1;">Pesquisa orientada à uma lista de palavras separadas por vírgulas. Não há sugestão de <i>tokens</i> relacionados.</p>';
            app.config_SearchModeListOfWords.Interpreter = 'html';
            app.config_SearchModeLabel = uilabel(app.config_Option2Grid);
            app.config_SearchModeLabel.Text = 'MODO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end