classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        menu_MainGrid                   matlab.ui.container.GridLayout
        menu_Button4Grid                matlab.ui.container.GridLayout
        menu_Button4Icon                matlab.ui.control.Image
        menu_Button4Label               matlab.ui.control.Label
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menu_Image                      matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Tab1_infoGrid                   matlab.ui.container.GridLayout
        points_Panel                    matlab.ui.container.Panel
        points_Grid                     matlab.ui.container.GridLayout
        points_editButton               matlab.ui.control.Button
        points_AntennaHeight            matlab.ui.control.NumericEditField
        points_AntennaHeightLabel       matlab.ui.control.Label
        points_Longitude                matlab.ui.control.NumericEditField
        points_LongitudeLabel           matlab.ui.control.Label
        points_Latitude                 matlab.ui.control.NumericEditField
        points_LatitudeLabel            matlab.ui.control.Label
        points_Tree                     matlab.ui.container.Tree
        points_refreshGrid              matlab.ui.container.GridLayout
        points_refreshImage             matlab.ui.control.Image
        points_TreeLabel                matlab.ui.control.Label
        layout_Grid                     matlab.ui.container.GridLayout
        layout_tableButton              ccTools.Button
        layout_histogramButton          ccTools.Button
        layout_pdfButton                ccTools.Button
        layout_mapButton                ccTools.Button
        layout_Intro                    matlab.ui.control.Label
        Tab_3                           matlab.ui.container.Tab
        Tab2_infoGrid                   matlab.ui.container.GridLayout
        filter_Tree                     matlab.ui.container.CheckBoxTree
        filter_addImage                 matlab.ui.control.Image
        filter_operationPanel           matlab.ui.container.ButtonGroup
        filter_operationValuesPanel     matlab.ui.container.Panel
        filter_operationValuesGrid      matlab.ui.container.GridLayout
        filter_operationLogical         matlab.ui.control.DropDown
        filter_operationLogicalLabel    matlab.ui.control.Label
        filter_operationLogicalReference  matlab.ui.control.DropDown
        filter_operationLogicalReferenceLabel  matlab.ui.control.Label
        filter_operationTextListValue   matlab.ui.control.DropDown
        filter_operationTextFreeValue   matlab.ui.control.EditField
        filter_operationNumValue2       matlab.ui.control.NumericEditField
        filter_operationSeparator       matlab.ui.control.Label
        filter_operationNumValue1       matlab.ui.control.NumericEditField
        filter_operationButton10        matlab.ui.control.ToggleButton
        filter_operationButton9         matlab.ui.control.ToggleButton
        filter_operationButton8         matlab.ui.control.ToggleButton
        filter_operationButton7         matlab.ui.control.ToggleButton
        filter_operationButton6         matlab.ui.control.ToggleButton
        filter_operationButton5         matlab.ui.control.ToggleButton
        filter_operationButton4         matlab.ui.control.ToggleButton
        filter_operationButton3         matlab.ui.control.ToggleButton
        filter_operationButton2         matlab.ui.control.ToggleButton
        filter_operationButton1         matlab.ui.control.ToggleButton
        filter_TypePanel                matlab.ui.container.ButtonGroup
        filter_TypeRadioButton12        matlab.ui.control.RadioButton
        filter_TypeRadioButton11        matlab.ui.control.RadioButton
        filter_TypeRadioButton10        matlab.ui.control.RadioButton
        filter_TypeRadioButton9         matlab.ui.control.RadioButton
        filter_TypeRadioButton8         matlab.ui.control.RadioButton
        filter_TypeRadioButton7         matlab.ui.control.RadioButton
        filter_TypeRadioButton6         matlab.ui.control.RadioButton
        filter_TypeRadioButton5         matlab.ui.control.RadioButton
        filter_TypeRadioButton4         matlab.ui.control.RadioButton
        filter_TypeRadioButton3         matlab.ui.control.RadioButton
        filter_TypeRadioButton2         matlab.ui.control.RadioButton
        filter_TypeRadioButton1         matlab.ui.control.RadioButton
        filter_Intro                    matlab.ui.control.Label
        Tab_2                           matlab.ui.container.Tab
        Tab_4                           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TreinopalavraButton             matlab.ui.control.Button
        PalavraaconsultarLabel          matlab.ui.control.Label
        AlgoritmoLabel                  matlab.ui.control.Label
        filter_Method                   matlab.ui.container.ButtonGroup
        filter_Method3                  matlab.ui.control.RadioButton
        filter_Method2                  matlab.ui.control.RadioButton
        filter_Method1                  matlab.ui.control.RadioButton
        Image                           matlab.ui.control.Image
        ListBox                         matlab.ui.control.ListBox
        BuscajarowinklerButton          matlab.ui.control.Button
        EditField_2                     matlab.ui.control.EditField
        BuscaLevenshteinButton          matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        FiltroButton                    matlab.ui.control.Button
        filter_userEntry                matlab.ui.control.EditField
        filter_TypePanel_2              matlab.ui.container.ButtonGroup
        filter_TypeRadioButton4_4       matlab.ui.control.RadioButton
        filter_TypeRadioButton8_3       matlab.ui.control.RadioButton
        filter_TypeRadioButton4_3       matlab.ui.control.RadioButton
        filter_TypeRadioButton8_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton7_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton6_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton5_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton3_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton2_2       matlab.ui.control.RadioButton
        filter_TypeRadioButton1_2       matlab.ui.control.RadioButton
        misc_Intro_2                    matlab.ui.control.Label
        toolGrid                        matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        tool_nRowsGrid                  matlab.ui.container.GridLayout
        tool_resetButton                matlab.ui.control.Image
        tool_nRowsIcon                  matlab.ui.control.Image
        tool_nRows                      matlab.ui.control.Label
        tool_simulationButton           matlab.ui.control.Button
        tool_PDFButton                  matlab.ui.control.Button
        tool_Serarator2                 matlab.ui.control.Image
        tool_simulationAxesVisibility   matlab.ui.control.StateButton
        tool_HistogramAxesVisibility    matlab.ui.control.StateButton
        tool_Serarator1                 matlab.ui.control.Image
        tool_ExportButton               matlab.ui.control.Button
        Document                        matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        DadosSCHTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SituaodocertificadoEditField    matlab.ui.control.EditField
        SituaodocertificadoEditFieldLabel  matlab.ui.control.Label
        ValidadedocertificadoEditField  matlab.ui.control.EditField
        ValidadedocertificadoEditFieldLabel  matlab.ui.control.Label
        CertificadodeconformidadeEditField  matlab.ui.control.EditField
        CertificadodeconformidadeEditFieldLabel  matlab.ui.control.Label
        ObservaesimportantesTextArea_2  matlab.ui.control.TextArea
        ObservaesimportantesTextArea_2Label  matlab.ui.control.Label
        OutrosnomesdoprodutoTextArea_2  matlab.ui.control.TextArea
        OutrosnomesdoprodutoTextArea_2Label  matlab.ui.control.Label
        CNPJdosolicitanteEditField      matlab.ui.control.EditField
        CNPJdosolicitanteEditFieldLabel  matlab.ui.control.Label
        NomedosolicitanteEditField      matlab.ui.control.EditField
        NomedosolicitanteEditFieldLabel  matlab.ui.control.Label
        SituaodorequerimentoEditField   matlab.ui.control.EditField
        SituaodorequerimentoEditFieldLabel  matlab.ui.control.Label
        NmerodehomologaoEditField       matlab.ui.control.EditField
        NmerodehomologaoEditFieldLabel  matlab.ui.control.Label
        TipodeprodutoEditField          matlab.ui.control.EditField
        TipodeprodutoEditFieldLabel     matlab.ui.control.Label
        NomeComercialEditField          matlab.ui.control.EditField
        NomeComercialEditFieldLabel     matlab.ui.control.Label
        ModeloEditField                 matlab.ui.control.EditField
        ModeloEditFieldLabel            matlab.ui.control.Label
        NomedofabricanteEditField       matlab.ui.control.EditField
        NomedofabricanteEditFieldLabel  matlab.ui.control.Label
        CheckBox_CadastroProdutos       matlab.ui.control.CheckBox
        CadastroSCH                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        RetornarButton                  matlab.ui.control.Button
        CadastrardadosButton            matlab.ui.control.Button
        ObservaesimportantesTextArea    matlab.ui.control.TextArea
        ObservaesimportantesTextAreaLabel  matlab.ui.control.Label
        OutrosnomesdoprodutoTextArea    matlab.ui.control.TextArea
        OutrosnomesdoprodutoTextAreaLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        LeftPanelVisibility             matlab.ui.control.Image
        filter_ContextMenu              matlab.ui.container.ContextMenu
        filter_delButton                matlab.ui.container.Menu
        filter_delAllButton             matlab.ui.container.Menu
        points_ContextMenu              matlab.ui.container.ContextMenu
        points_referenceButton          matlab.ui.container.Menu
        points_editRowButton            matlab.ui.container.Menu
        points_delButton                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General

        RootFolder
        userPath

        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4

        restoreView = {[0,1], [0,1]}

        compVisibilityStatus = [true, false, false, false]
        compCustomization    = true

        rfDataHub
        rfDataHubSummary
        rfDataHubLogical

        pointsTable = table('Size',          [0, 6],                                                    ...
                            'VariableTypes', {'cell', 'double', 'double', 'cell', 'double', 'logical'}, ...
                            'VariableNames', {'Type', 'Latitude', 'Longitude', 'Location', 'AntennaHeight', 'Reference'})

        filterTable = table('Size',          [0, 9],                                                                      ...
                            'VariableTypes', {'cell', 'int8', 'int8', 'cell', 'cell', 'int8', 'cell', 'logical', 'cell'}, ...
                            'VariableNames', {'Order', 'ID', 'RelatedID', 'Type', 'Operation', 'Column', 'Value', 'Enable', 'uuid'})

        elevationCache = struct('txSiteID', {}, 'rxSiteID', {}, 'PointsPerLink', {}, 'Server', {}, 'wayPoints3D', {}, 'zMatrix', {}, 'zMatrixReference', {})
    end
    

    properties (Access = private)
        rawSCHTable
        rawSCHTable_uniqueModels

        previousSearch = ''
        lastSearch     = ''

        matchDistance
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_WindowPosition(app)
            app.UIFigure.Name = sprintf('%s %s', class.Constants.appName, class.Constants.appRelease);
            fcn.winPosition(app)
            app.UIFigure.WindowState = 'maximized';
        end


        %-----------------------------------------------------------------%
        function startup_jsBackDoor(app)
            app.jsBackDoor.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', ...
                                                fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
        end


        %-----------------------------------------------------------------%
        function startup_ConfigFiles(app, webAppFlag)
            if webAppFlag
                app.General = jsondecode(fileread(fullfile(app.RootFolder, 'Settings', 'GeneralSettings.json')));
                
                if isempty(app.General.Merge.Distance)
                    app.General.Merge.Distance = Inf;
                end
    
                if isempty(app.General.Integration.Trace)
                    app.General.Integration.Trace = Inf;
                end
    
                app.General.version = fcn.startup_Versions("Startup", app.RootFolder);
                
                % tempfolder
                if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                    mkdir(fullfile(app.RootFolder, 'Temp'))
                end
    
                % userPath
                userPaths = class.Constants.userPaths;
                if ~isempty(app.General.userPath)
                    userPaths(end+1) = {app.General.userPath};
                end
    
                for ii = numel(userPaths):-1:1
                    if ~isfolder(userPaths{ii})
                        userPaths(ii) = [];
                    end
                end
                app.General.userPath = userPaths{end};
                app.userPath         = userPaths{end};
            
            else
                app.General          = app.CallingApp.General;
                app.userPath         = app.CallingApp.menu_userPath.Value;
            end
        end


        %-----------------------------------------------------------------%
        function startup_compCustomization(app, webAppFlag)
            % Implementar depois o retorno do JS >> Matlab, indicando os
            % valores dos parâmetros programados, confirmando, então, se
            % foi realizada a alteração desejada.
            % try
            %     ccTools.compCustomizationV2(app.jsBackDoor, app.UITable, "fontSize", "11px", "paddingTop", "0px")
            % catch
            % end

            % Em relação ao painel de controle, troquei o sistema de visibilidade 
            % de linhas de um Grid por um sistema de abas. Em tese, isso
            % deve tornar a inicialização do webapp mais eficiente porque
            % somente serão renderizadas em tela os componentes da aba
            % selecionada. 
            % Acontece que ao fazer isso, a janela de progresso do MATLAB
            % WEB SERVER não é excluída após a carga do app. Criei o evento
            % para excluí-la.
            if webAppFlag
                sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");
            end
        end


        %-----------------------------------------------------------------%
        function startup_ReadRawSCH(app)

            app.rawSCHTable = fcn.ReadRawSCHTable(fullfile(app.RootFolder, 'DataBase', 'Produtos_Homologados_Anatel.csv'));
            app.rawSCHTable = app.rawSCHTable(:,[12 13 14 16 2 11 3 4 5 7 9]);

            app.rawSCHTable_uniqueModels = fcn.PreProcessedData(app.rawSCHTable.('Nome Comercial'));
            app.matchDistance = zeros(numel(app.rawSCHTable_uniqueModels), 1);

            app.UITable.Data = app.rawSCHTable;
            app.UITable.ColumnName = app.rawSCHTable.Properties.VariableNames;

        end


        %-----------------------------------------------------------------%
        function layout_FilterOperationPanel(app, filterType, filterDefault)
            
            layout_FilterDefaultValues(app)
            filter_operationLogicalReferenceValueChanged(app)

            hComp = findobj(app.filter_operationPanel, 'Type', 'uitogglebutton');
            hCompTagFlag = contains(arrayfun(@(x) x.Tag, hComp, 'UniformOutput', false), filterType);

            set(hComp(hCompTagFlag),  Enable=1)
            set(hComp(~hCompTagFlag), Enable=0)

            selectedButton = app.filter_operationPanel.SelectedObject;
            if ~selectedButton.Enable
                switch filterType
                    case {'textList1', 'ROI'}
                        app.filter_operationButton3.Value = 1; % CONTÉM

                    otherwise
                        app.filter_operationButton1.Value = 1; % IGUAL
                end
            end

            switch filterType
                case 'numeric'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {22,0,0};

                case 'textFree'
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,22,0};

                case {'textList1', 'textList2', 'ROI'}
                    app.filter_operationValuesGrid.RowHeight(1:3) = {0,0,22};
                    app.filter_operationTextListValue.Items = filterDefault;
            end
        end


        %-----------------------------------------------------------------%
        function layout_FilterDefaultValues(app)
            app.filter_operationNumValue1.Value     = -1;
            app.filter_operationNumValue2.Value     = -1;
            app.filter_operationTextFreeValue.Value = '';
            app.filter_operationTextListValue.Items = {};
            app.filter_operationLogical.Items       = {'E (&&)'};
        end


        %-----------------------------------------------------------------%
        function filter_TreeBuilding(app)
            if ~isempty(app.filter_Tree.Children)
                delete(app.filter_Tree.Children)
            end

            idx1 = find(strcmp(app.filterTable.Order, 'Node'))';
            if ~isempty(idx1)
                checkedNodes = {};
                for ii = idx1
                    parentNode = uitreenode(app.filter_Tree, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(ii),                        ...
                                                                                                            app.filterTable.Type{ii},                      ...
                                                                                                            app.filterTable.Operation{ii},                 ...
                                                                                                            filter_Value(app, app.filterTable.Value{ii})), ...
                                                             'NodeData', ii, 'ContextMenu', app.filter_ContextMenu);
                    if app.filterTable.Enable(ii)
                        checkedNodes = [checkedNodes, parentNode];
                    end
    
                    idx2 = find(app.filterTable.RelatedID == ii)';                
                    for jj = idx2
                        childNode = uitreenode(parentNode, 'Text', sprintf('#%d: RFDataHub.("%s") %s %s', app.filterTable.ID(jj),                        ...
                                                                                                          app.filterTable.Type{jj},                      ...
                                                                                                          app.filterTable.Operation{jj},                 ...
                                                                                                          filter_Value(app, app.filterTable.Value{jj})), ...
                                                           'NodeData', jj, 'ContextMenu', app.filter_ContextMenu);
    
                        if app.filterTable.Enable(jj)
                            checkedNodes = [checkedNodes, childNode];
                        end
                    end
                end
                app.filter_Tree.CheckedNodes = checkedNodes;
                expand(app.filter_Tree, 'all')
            end

            app.filter_operationLogicalReference.Items = [{''}, cellstr("#" + string((idx1)))];
        end


        %-----------------------------------------------------------------%
        function filterValue = filter_Value(app, filterValue)
            if isnumeric(filterValue)
                if numel(filterValue) == 1
                    filterValue = string(filterValue);
                else
                    filterValue = "[" + strjoin(string(filterValue), ', ') + "]";
                end
            elseif ischar(filterValue)
                filterValue = sprintf('"%s"', upper(filterValue));
            else
                filterValue = '⌂';
            end
        end


        %-----------------------------------------------------------------%
        function filter_TableFiltering(app, ProgressDialogFlag)
            if ProgressDialogFlag
                d = uiprogressdlg(app.UIFigure, "Message", '<font style="font-size:12;">Em andamento...</font>', "Interpreter", "html", "Indeterminate", "on");
            end

            app.rfDataHubLogical   = fcn.TableFiltering(app.rfDataHub, app.filterTable);

            if isempty(app.UITable.Selection)
                initialSelectedRow = app.UIAxes4.UserData.ID;
            else
                initialSelectedRow = app.UITable.Data.ID{app.UITable.Selection(1)};
            end

            app.UITable.Selection  = [];
            app.UITable.Data       = app.rfDataHub(app.rfDataHubLogical,[30, 1, 6:7, 31, 4:5, 12, 13, 29, 32]);

            if ~isempty(initialSelectedRow)
                finalSelectedRow = find(strcmp(app.UITable.Data.ID, initialSelectedRow), 1);
                if ~isempty(finalSelectedRow)
                    app.UITable.Selection = [finalSelectedRow, 1];
                end                
            end

            filter_TableStyle(app, 'ProgrammaticallySelectedRow')
            filter_TableStyle(app, 'TerrainProfilePlot')

            NN = sum(app.rfDataHubLogical);
            MM = numel(app.rfDataHubLogical);
            app.tool_nRows.Text = sprintf('%d de %d registros\n%.1f %%', NN, MM, (NN/MM)*100);

            plot_General(app)
            plot_SelectedRow(app)
            plot_Nodes(app)
            if app.tool_simulationAxesVisibility.Value
                plot_simulationLink(app)
            end
            drawnow

            if ProgressDialogFlag
                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function filter_TableStyle(app, Type)
            switch Type
                case {'SelectionRowChanged', 'ProgrammaticallySelectedRow'}
                    idx1 = find(app.UITable.StyleConfigurations.Target == "cell");
                    if ~isempty(idx1)
                        removeStyle(app.UITable, idx1)
                    end

                    if strcmp(Type, 'ProgrammaticallySelectedRow')
                        if ~isempty(app.UITable.Selection)
                            idx2 = app.UITable.Selection(1);
                            addStyle(app.UITable, uistyle("BackgroundColor", '#ddf0ff'), 'cell', [idx2,1])
                        end
                    end

                case 'HorizontalAlign'
                    addStyle(app.UITable, uistyle('HorizontalAlignment', 'right'), 'column', [8,10])
            end
        end       
        
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            try
                % PATH SEARCH
                if nargin == 1
                    webAppFlag     = true;
                    app.RootFolder = fileparts(mfilename('fullpath'));                    
                else
                    webAppFlag     = false;
                    app.CallingApp = mainapp;
                    app.RootFolder = mainapp.RootFolder;
                end

                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')

                % STARTUP
                cd(app.RootFolder)
                
                startup_WindowPosition(app)
                startup_jsBackDoor(app)
                startup_ConfigFiles(app, webAppFlag)
                startup_ReadRawSCH(app)

                drawnow

                % startup_compCustomization(app, webAppFlag)

            catch ME
                layoutFcn.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            if ~isempty(app.CallingApp)
                app.CallingApp.auxiliarWin8 = [];
            end

            delete(app)
            
        end

        % Image clicked function: tool_resetButton
        function resetFcn(app, event)
            
            msg = ['<font style="font-size:12;">Essa funcionalidade possibilita reinicializar alguns dos parâmetros do <i>app</i>, ' ...
                   'como a INTERAÇÃO com os plots, a exclusão de edições manuais na lista dos PONTOS DE MONITORAÇÃO e FILTROS.</font>'];
            selection = uiconfirm(app.UIFigure, msg, '', 'Options', {'Interação', 'Pontos', 'Filtros', 'Cancelar'}, 'DefaultOption', 1, 'Icon', 'question', 'Interpreter', 'html');

            if strcmp(selection, 'Cancelar')
                return
            end

            switch selection
                case 'Interação'
                    plotFcn.axesInteraction.DisableDefaultInteractions([app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4])
                    pause(.1)

                    plotFcn.axesInteraction.EnableDefaultInteractions([app.UIAxes1, app.UIAxes2, app.UIAxes3, app.UIAxes4])

                case 'Pontos'
                    points_refreshImageClicked(app)

                case 'Filtros'
                    filter_delFilter(app, struct('Source', app.filter_delAllButton))
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function layout_LeftPanelSelectedTabControl(app, event)
                        
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_MainGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 22;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_MainGrid.ColumnWidth = columnWidth;
            app.menu_Image.Layout.Column  = idx;
            app.TabGroup.SelectedTab      = app.TabGroup.Children(idx);

            focus(app.jsBackDoor)
            
        end

        % Image clicked function: LeftPanelVisibility
        function layout_LeftPanelVisibilityControl(app, event)
            
            if app.Grid.ColumnWidth{1} == 300
                app.LeftPanelVisibility.ImageSource = 'LT_HideRight.png';
                app.Grid.ColumnWidth{1} = 0;
            else
                app.LeftPanelVisibility.ImageSource = 'LT_HideLeft.png';
                app.Grid.ColumnWidth{1} = 300;
            end

            focus(app.jsBackDoor)

        end

        % Callback function: layout_histogramButton, layout_mapButton, 
        % ...and 2 other components
        function layout_compButtonPushed(app, event)
            
            switch event.Source
                case app.layout_mapButton
                    idx = 1;

                case app.layout_histogramButton
                    idx = 2;

                case app.layout_tableButton
                    idx = 3;
                    if app.compCustomization
                        app.compCustomization = false;
                        try
                            ccTools.compCustomizationV2(app.jsBackDoor, app.UITable, "fontSize", "11px", "paddingTop", "0px")
                        catch
                        end
                    end

                case app.layout_pdfButton
                    idx = 4;
            end
            app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);

            if all(~app.compVisibilityStatus)
                app.compVisibilityStatus(idx) = ~app.compVisibilityStatus(idx);
                return
            end

            % % Layout vertical
            if     all(app.compVisibilityStatus([1,2,3]))                               
                   app.Document.RowHeight = {'1x','.5x','.5x'};
            elseif all(app.compVisibilityStatus([1,2]))                                
                   app.Document.RowHeight = {'1.5x','.5x',0};
            elseif all(app.compVisibilityStatus([1,3]))                                 
                   app.Document.RowHeight = {'1.5x',0,'.5x'};
            elseif all(app.compVisibilityStatus([2,3])) 
                if app.compVisibilityStatus(4)
                    app.Document.RowHeight = {0,'1.5x','.5x'};
                else                           
                    app.Document.RowHeight = {0,'1x','1x'}; 
                end
            elseif app.compVisibilityStatus(1)                                          
                app.Document.RowHeight = {'2x',0,0};
            elseif app.compVisibilityStatus(2)                                        
                app.Document.RowHeight = {0,'2x',0};
            elseif app.compVisibilityStatus(3)          
                if app.compVisibilityStatus(4)
                    app.Document.RowHeight = {0,'1.5x','.5x'};
                else                           
                    app.Document.RowHeight = {0,0,'2x'}; 
                end
            else                                                                        
                app.Document.RowHeight = {'1x','.5x',0};
            end

            % Layout horizontal
            if any(app.compVisibilityStatus([1,2]))     
                if app.compVisibilityStatus(4) 
                    app.Document.ColumnWidth = {'1x','1x'};
                else                           
                    app.Document.ColumnWidth = {'1x',0};
                end
            else                                                                        
                app.Document.ColumnWidth = {0,'1x'};
            end

            % Cor e visibilidade            
            if app.compVisibilityStatus(idx)
                event.Source.BackgroundColor = [0.96,0.96,0.96];
                if idx == 3; app.UITable.Visible = 1;
                end
            else
                event.Source.BackgroundColor = [1,1,1];
                if idx == 3; app.UITable.Visible = 0;
                end
            end

            % Habilita/desabilita botões relacionados ao eixo cartesiano
            if app.compVisibilityStatus(2)
                app.tool_HistogramAxesVisibility.Enable  = 1;
                app.tool_simulationAxesVisibility.Enable = 1;
            else
                app.tool_HistogramAxesVisibility.Enable  = 0;
                app.tool_simulationAxesVisibility.Enable = 0;
            end
            drawnow
            
        end

        % Selection changed function: filter_TypePanel
        function filter_typePanelSelectionChanged(app, event)
                        
            selectedButton = app.filter_TypePanel.SelectedObject;
            switch selectedButton
                case app.filter_TypeRadioButton1                            % BASE DE DADOS
                    filterType = 'textList1';
                    
                    tempDefault   = cellfun(@(x) strsplit(x, ' | '), app.rfDataHubSummary.Source.Categories, 'UniformOutput', false);
                    filterDefault = {};
                    for ii = 1:numel(tempDefault)
                        filterDefault = [filterDefault, tempDefault{ii}];
                    end
                    filterDefault = unique(filterDefault);

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA
                    filterType = 'numeric';
                    filterDefault = {};

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    filterType = 'textFree';
                    filterDefault = {};

                case app.filter_TypeRadioButton9                            % UF
                    filterType = 'textList2';
                    filterDefault = app.rfDataHubSummary.State.Categories;

                case app.filter_TypeRadioButton12                           % ROI
                    filterType = 'ROI';
                    filterDefault = {'CÍRCULO', 'RETÂNGULO', 'POLÍGONO'};
            end

            layout_FilterOperationPanel(app, filterType, filterDefault)
            filter_operationPanelSelectionChanged(app)
            
        end

        % Selection changed function: filter_operationPanel
        function filter_operationPanelSelectionChanged(app, event)
            
            selectedButton = app.filter_operationPanel.SelectedObject;
            switch selectedButton
                case {app.filter_operationButton9, app.filter_operationButton10}
                    app.filter_operationSeparator.Enable = 1;
                    app.filter_operationNumValue2.Enable = 1;
                    
                otherwise
                    app.filter_operationSeparator.Enable = 0;
                    app.filter_operationNumValue2.Enable = 0;
            end
            
        end

        % Value changed function: filter_operationLogicalReference
        function filter_operationLogicalReferenceValueChanged(app, event)
            
            value = app.filter_operationLogicalReference.Value;
            if isempty(value)
                app.filter_operationLogical.Items = {'E (&&)'};
            else
                app.filter_operationLogical.Items = {'OU (||)'};
            end
            
        end

        % Image clicked function: filter_addImage
        function filter_addFilter(app, event)
            
            selectedFilterType      = app.filter_TypePanel.SelectedObject;
            selectedFilterOperation = app.filter_operationPanel.SelectedObject;

            if isempty(app.filter_operationLogicalReference.Value)
                Order     = 'Node';
                RelatedID = -1;
            else
                Order     = 'Child';
                RelatedID = str2double(app.filter_operationLogicalReference.Value(2:end));
            end
            UUID = char(matlab.lang.internal.uuid());

            switch selectedFilterType
                case {app.filter_TypeRadioButton1, ...                      % BASE DE DADOS
                      app.filter_TypeRadioButton9}                          % UF
                    Value = app.filter_operationTextListValue.Value;

                case {app.filter_TypeRadioButton2, ...                      % FREQUÊNCIA
                      app.filter_TypeRadioButton3, ...                      % LARGURA BANDA
                      app.filter_TypeRadioButton6, ...                      % FISTEL
                      app.filter_TypeRadioButton7, ...                      % SERVIÇO
                      app.filter_TypeRadioButton8, ...                      % ESTAÇÃO
                      app.filter_TypeRadioButton11}                         % DISTÂNCIA

                    if ismember(selectedFilterOperation.Text, {'<>', '><'})
                        Value = [app.filter_operationNumValue1.Value, ...
                                 app.filter_operationNumValue2.Value];
                    else
                        Value = app.filter_operationNumValue1.Value;
                    end

                case {app.filter_TypeRadioButton4, ...                      % CLASSE EMISSÃO (DESIGNAÇÃO)
                      app.filter_TypeRadioButton5, ...                      % ENTIDADE
                      app.filter_TypeRadioButton10}                         % MUNICÍPIO
                    Value = app.filter_operationTextFreeValue.Value;            
            end

            newFilter     = {Order, height(app.filterTable)+1, RelatedID,           ...
                             selectedFilterType.Text, selectedFilterOperation.Text, ...
                             str2double(selectedFilterType.Tag), Value, true, UUID};
            newFilterFlag = true;

            for ii = 1:height(app.filterTable)
                if isequal(app.filterTable{ii,[4,5,7]}, newFilter([4,5,7]))
                    newFilterFlag = false;
                    break
                end
            end

            if ~newFilterFlag
                msg = 'Filtro já incluído!';
                layoutFcn.modalWindow(app.UIFigure, 'ccTools.MessageBox', msg);
                
                return
            end

            app.filterTable(end+1,[1:6,8:9]) = newFilter([1:6,8:9]);
            app.filterTable.Value{end} = Value;

            filter_TreeBuilding(app)
            filter_TableFiltering(app, true)

        end

        % Menu selected function: filter_delAllButton, filter_delButton
        function filter_delFilter(app, event)
            
            if isempty(app.filterTable)
                return
            end

            switch event.Source
                case app.filter_delButton
                    if isempty(app.filter_Tree.SelectedNodes)
                        return
                    end
                    idx1 = arrayfun(@(x) x.NodeData, app.filter_Tree.SelectedNodes);
                    
                    % Identifica se algum dos fluxos selecionado é um nó de
                    % filtros, inserindo na lista os seus filhos.
                    idx1 = [idx1, find(ismember(app.filterTable.RelatedID, idx1))'];

                case app.filter_delAllButton
                    idx1 = 1:height(app.filterTable);
            end 
    
            if ~isempty(idx1)
                % Apaga os ROI's, caso existentes.
                idx2 = find(strcmp(app.filterTable.Type, 'ROI'))';
                for ii = idx2
                    if ismember(ii, idx1)
                        delete(findobj(app.UIAxes1.Children, Tag=sprintf('ROI_%s', app.filterTable.uuid{ii})))
                    end
                end                

                % E depois atualiza a tabela de filtros, a tabela renderizada
                % no app e, por fim, o plot.
                app.filterTable(idx1,:) = [];

                filter_TreeBuilding(app)
                filter_TableFiltering(app, true)
            end

        end

        % Callback function: filter_Tree
        function filter_TreeCheckedNodesChanged(app, event)
            
            hTree             = findobj(app.filter_Tree, '-not', 'Type', 'uicheckboxtree');
            hTreeNodeDataList = arrayfun(@(x) x.NodeData, hTree);
            hCheckedNodeData  = arrayfun(@(x) x.NodeData, app.filter_Tree.CheckedNodes);

            disableIndexList  = setdiff(hTreeNodeDataList, hCheckedNodeData);
            enableIndexList   = setdiff((1:height(app.filterTable))', disableIndexList);

            app.filterTable.Enable(disableIndexList) = false;
            app.filterTable.Enable(enableIndexList)  = true;

            filter_TableFiltering(app, true)
            
        end

        % Button pushed function: FiltroButton
        function FiltroButtonPushed(app, event)
           
            logicalIndexFiltered = contains(app.rawSCHTable{:,3}, strtrim(app.filter_userEntry.Value), 'IgnoreCase', true);
            app.UITable.Data     = app.rawSCHTable(logicalIndexFiltered,:);

        end

        % Double-clicked callback: UITable
        function UITableDoubleClicked(app, event)
            
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;
            app.UITable.Data{displayRow,1}
            app.NomedofabricanteEditField.Value = string(app.UITable.Data{displayRow,1});
            app.ModeloEditField.Value = string(app.UITable.Data{displayRow,2});
            app.NomeComercialEditField.Value = string(app.UITable.Data{displayRow,3});
            app.TipodeprodutoEditField.Value = string(app.UITable.Data{displayRow,4});
            app.NmerodehomologaoEditField.Value = string(app.UITable.Data{displayRow,5});
            app.SituaodorequerimentoEditField.Value = string(app.UITable.Data{displayRow,6});
            app.NomedosolicitanteEditField.Value = string(app.UITable.Data{displayRow,7});
            app.CNPJdosolicitanteEditField.Value = string(app.UITable.Data{displayRow,8});
            app.CertificadodeconformidadeEditField.Value = string(app.UITable.Data{displayRow,9});
            app.ValidadedocertificadoEditField.Value = string(app.UITable.Data{displayRow,10});        
            app.SituaodocertificadoEditField.Value = string(app.UITable.Data{displayRow,11});
            
        end

        % Value changing function: filter_userEntry
        function filter_userEntryValueChanging(app, event)

            value2Search  = fcn.PreProcessedData(event.Value);
            rawColumnData = app.rawSCHTable_uniqueModels;

            if ~isempty(value2Search) && ~strcmp(value2Search, app.lastSearch)
                switch app.filter_Method.SelectedObject
                    %-----------------------------------------------------%
                    case app.filter_Method1 % Contains
                        idxFiltered = startsWith(rawColumnData, value2Search);

                    %-----------------------------------------------------%
                    case app.filter_Method2 % Levenshtein
                        HH = numel(rawColumnData);
                        MM = numel(value2Search);

                        for ii = 1:HH
                            NN = numel(rawColumnData{ii});
                            
                            d = zeros(MM+1, NN+1);
                            d(:,1) = (0:MM)';
                            d(1,:) = 0:NN;
                                
                            for jj = 1:NN
                                for kk = 1:MM
                                    if value2Search(kk) == rawColumnData{ii}(jj)
                                        cost = 0;
                                    else
                                        cost = 1;
                                    end
                                    d(kk+1,jj+1) = min([d(kk,jj+1)+1, d(kk+1,jj)+1, d(kk,jj)+cost]);
                                end
                            end
                            app.matchDistance(ii) = d(MM+1,NN+1);     
                        end

                        % Aqui precisa definir o approach... quanto mais
                        % valores a apresentar (10, ou 100, 1000, por
                        % exemplo), maior o tempo de renderização da lista
                        % no ListBox. E vai vir algumas merdas... podemos
                        % deixar isso configurável ou evoluir o algoritmo
                        % de busca, analisando os matchs nas posições
                        % iniciais, limpeza dos valores de referência
                        % (tokenização) etc.

                        % OPÇÃO 1: MOSTRAR OS VALORES CUJAS NOTAS SÃO
                        % "MENOR NOTA" E "MENOR NOTA+1";

                        % minDistance = min(app.matchDistance);
                        % idxFiltered = [find(app.matchDistance == minDistance); find(app.matchDistance == minDistance+1)];

                        % OPÇÃO 2: MOSTRAR OS 100 VALORES QUE POSSUEM AS
                        % MENORES NOTAS.

                        [~, idxSortedData] = sort(app.matchDistance);
                        idxFiltered = idxSortedData(1:20); % Inserir depois o controle para definir quantos resultados mostrar...

                    %-----------------------------------------------------%
                    case app.filter_Method3 % Jarowinkler
                        s1 = value2Search;
            
                        % Iterar sobre todas as palavras na base de dados
                        for ii = 1:numel(rawColumnData)
                            % jw = jarowinkler(s1,s2) calculates the Jaro Winker string distance between
                            % strings s1 and s2
                            rawColumnValue = replace(rawColumnData{ii}, ' ', '');                     
                              
                            s2 = rawColumnValue;
                            s1l = length(s1);
                            s2l = length(s2);
                            s11 = repmat(s1,s2l,1);
                            s22 = repmat(s2',1,s1l);
                            % Calculate the matching distance
                            try
                                mtxwin = floor(max(length(s1),length(s2))/2)-1;
                                mtxmat = double(s11 == s22);
                                % Ignore the matched characters outside of matching distance
                                mtxmat = mtxmat - tril(mtxmat,-mtxwin-1) - triu(mtxmat,mtxwin+1);
                                % Ignore additional matches beyond first match
                                m = zeros(s2l,s1l);
                                for i=1:s1l
                                    if any(mtxmat(:,i))
                                        fmatid = find(~any(m,2) & mtxmat(:,i),1);
                                        m(fmatid,i) = mtxmat(fmatid,i);
                                    end
                                end
                                s1m = any(m);
                                s2m = any(m,2);
                                % Calculate the number of transpositions
                                t = sum(~mtxmat(sub2ind(size(m),find(s2m),find(s1m)')))/2;% Calculate the number of matches
                                m = sum(s1m);
                                % Jaro distance
                                dj = (m/s1l + m/s2l + (m-t)/m)/3;
                                % Winkler modification
                                jw = dj + 0.1*(s1(1:3)==s2(1:3))*cumprod(s1(1:3)==s2(1:3))'*(1-dj);
                                app.matchDistance(ii) = jw; 
                            catch
                                jw = 0;
                            end
                        end                                                                                                                   

                        tabela_sem_zeros = app.matchDistance(app.matchDistance ~= 0);
                        tabela_sem_zeros_unique = unique(tabela_sem_zeros);
                        matriz_tabela_sem_zeros_unique = zeros(numel(tabela_sem_zeros_unique), 2);
                        ww= numel(tabela_sem_zeros_unique);
                        for yx= 1:ww
                            matriz_tabela_sem_zeros_unique(yx,1) = sort(tabela_sem_zeros_unique(yx));
                            matriz_tabela_sem_zeros_unique(yx,2) = yx;
                            yx = yx+1;
                        end

                        for xy = 1:numel(app.matchDistance)
                            for pp = 1:numel(tabela_sem_zeros_unique)
                                if  app.matchDistance(xy) == matriz_tabela_sem_zeros_unique(pp,1)
                                    app.matchDistance(xy) = matriz_tabela_sem_zeros_unique(pp,2);
                                end
                            end
                        end
                        
                        minDistance = min(app.matchDistance)+1;
                        idxFiltered = [find(app.matchDistance == minDistance); find(app.matchDistance == numel(tabela_sem_zeros_unique))];
                    end
                  app.ListBox.Items    = app.rawSCHTable_uniqueModels(idxFiltered);
            elseif strcmp(value2Search, app.lastSearch)
                return
            else
                app.ListBox.Items  = {};
            end

            app.previousSearch = app.lastSearch;
            app.lastSearch     = value2Search;
            drawnow
        end

        % Button pushed function: BuscaLevenshteinButton
        function BuscaLevenshteinButtonPushed(app, event)
        % Palavra de entrada
        palavra = app.EditField.Value;
        
        % Base de dados
        dados = app.rawSCHTable{:,3};
        base_de_dados = dados;

        % Encontrar a palavra mais próxima
        % Inicializar a distância mínima como infinito
        distancia_minima =  inf;
        palavra_mais_proxima = '';
    
        % Iterar sobre todas as palavras na base de dados
        for k = 1:numel(base_de_dados)
        % Calcular a distância de Levenshtein entre a palavra de entrada e a palavra atual
        m = numel(palavra);
        n = numel(base_de_dados{k});
        d = zeros(m+1, n+1);
        
        for i = 1:m
            d(i+1,1) = i;
        end
        for j = 1:n
            d(1,j+1) = j;
        end
        
        for j = 1:n
            for i = 1:m
                if palavra(i) == base_de_dados{k}(j)
                    cost = 0;
                else
                    cost = 1;
                end
                d(i+1,j+1) = min([d(i,j+1)+1, d(i+1,j)+1, d(i,j)+cost]);
            end
        end
        distancia = d(m+1,n+1);
         % Se a distância for menor que a mínima registrada até agora
            % Atualizar a palavra mais próxima e a distância mínima
            if distancia < distancia_minima
                distancia_minima = distancia
                palavra_mais_proxima = base_de_dados{k};
            end
        end
       app.EditField.Value = palavra_mais_proxima;
        end

        % Button pushed function: BuscajarowinklerButton
        function BuscajarowinklerButtonPushed(app, event)
        % Palavra de entrada
        palavra = app.EditField_2.Value;
        
        % Base de dados
        dados = app.UITable.Data{:,3};
        base_de_dados = dados;

        % Encontrar a palavra mais próxima
        % Inicializar a distância mínima como zero
        distancia_minima =  0;
        palavra_mais_proxima = '';
    
        % Iterar sobre todas as palavras na base de dados
        for k = 1:numel(base_de_dados)
            % jw = jarowinkler(s1,s2) calculates the Jaro Winker string distance between
            % strings s1 and s2
    
            % m = numel(palavra);
            % n = numel(base_de_dados{k});
            
            try
                s1 = lower(palavra);
                s2 = lower(base_de_dados{k});
                s1l = length(s1);
                s2l = length(s2);
                s11 = repmat(s1,s2l,1);
                s22 = repmat(s2',1,s1l);
                % Calculate the matching distance
                mtxwin = floor(max(length(s1),length(s2))/2)-1;
                mtxmat = double(s11 == s22);
                % Ignore the matched characters outside of matching distance
                mtxmat = mtxmat - tril(mtxmat,-mtxwin-1) - triu(mtxmat,mtxwin+1);
                % Ignore additional matches beyond first match
                m = zeros(s2l,s1l);
                    for i=1:s1l
                        if any(mtxmat(:,i))
                            fmatid = find(~any(m,2) & mtxmat(:,i),1);
                            m(fmatid,i) = mtxmat(fmatid,i);
                        end
                    end
                s1m = any(m);
                s2m = any(m,2);
                % Calculate the number of transpositions
                t = sum(~mtxmat(sub2ind(size(m),find(s2m),find(s1m)')))/2;
                % Calculate the number of matches
                m = sum(s1m);
                % Jaro distance
                dj = (m/s1l + m/s2l + (m-t)/m)/3;
                % Winkler modification
                jw = dj + 0.1*(s1(1:3)==s2(1:3))*cumprod(s1(1:3)==s2(1:3))'*(1-dj);
                distancia = jw;
                % Se a distância for menor que a mínima registrada até agora
                % Atualizar a palavra mais próxima e a distância mínima
                    if distancia_minima < distancia
                        distancia_minima = distancia;
                        palavra_mais_proxima = base_de_dados{k};
                    end
            catch            
            end    
        end    
        % app.EditField_2.Value = palavra_mais_proxima; 
        end

        % Double-clicked callback: ListBox
        function ListBoxDoubleClicked(app, event)
            
            app.filter_userEntry.Value = app.ListBox.Items{event.InteractionInformation.Item};
            filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            % app.filter_userEntry.Value = app.previousSearch;
            % filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))
            % Exemplo de correção de palavras digitadas incorretamente usando Naive Bayes

% Criar um conjunto de dados de exemplo de palavras corretas e incorretas
palavras_corretas = {'MORFFYUL'};
palavras_incorretas = {'M(O-RFFYUL'},
% palavras_incorretas = {['M(O-RFFYUL','M*ORF)FYUL','Mn#ORFFYUL','"MORF,FYUjL"','McORFFdYUL','MO!RFFY+A20','UL,MORF(cFYUL','"Mm,ORFFYUL"','McORFFYcUL','M+ORFFYU.L','MOR+FF']};

% Criar um conjunto de treinamento consistindo de pares de palavras corretas e incorretas
treinamento = [palavras_corretas; palavras_incorretas];

% Criar rótulos para os dados de treinamento
rotulos = categorical([ones(1, numel(palavras_corretas)), zeros(1, numel(palavras_incorretas))]);

% Criar um modelo Naive Bayes usando os dados de treinamento
modelo = fitcnb(treinamento, rotulos);

% Palavra digitada pelo usuário (incorreta)
palavra_digitada = app.EditField.Value;

% Corrigir a palavra usando o modelo Naive Bayes
palavra_corrigida = predict(modelo, {palavra_digitada});
app.EditField_2.Value = palavra_corrigida;

        end

        % Selection changed function: filter_Method
        function filter_MethodSelectionChanged(app, event)
            
            app.lastSearch = '';
            filter_userEntryValueChanging(app, struct('Value', app.filter_userEntry.Value))
            
        end

        % Value changed function: CheckBox_CadastroProdutos
        function CheckBox_CadastroProdutosValueChanged(app, event)
            value = app.CheckBox_CadastroProdutos.Value;
           app.TabGroup2.SelectedTab = app.CadastroSCH; 
        end

        % Button pushed function: RetornarButton
        function RetornarButtonPushed(app, event)
           app.TabGroup2.SelectedTab = app.DadosSCHTab; 
           app.CheckBox_CadastroProdutos.Value = 0;
        end

        % Button pushed function: TreinopalavraButton
        function TreinopalavraButtonPushed(app, event)
            caminho_arquivo = 'C:\P&D\SCH\DataBase\meu_vetor_de_celulas.csv';
            palavra_original = 'MORFFYUL';
 for k = 1:20
taxa_de_erro = 0.2; % 10% de erro
%palavra_com_erros = inserir_caracteres_e_letras_errados(palavra_original, taxa_de_erro);

palavra = palavra_original;
    % Converte a palavra para uma matriz de caracteres
    letras = char(palavra);
    
    % Obtém o número de letras na palavra
    num_letras = numel(letras);
    
    % Calcula o número de caracteres a serem inseridos com base na taxa de erro
    num_erros = round(num_letras * taxa_de_erro);
    
    % Gera índices aleatórios para inserção de caracteres errados
    indices_insercao = randperm(num_letras, num_erros);
    
    % Caracteres errados possíveis
    caracteres_errados = ['@', '#', '$', '%', '&', '*', '+', '-', '_', '?', '!', '.', ',', ';', ':', '(', ')'];
    
    % Letras erradas possíveis
    letras_erradas = ['x', 'y', 'z', 'q', 'w', 'k', 'j', 'p', 'b', 'g', 'h', 'v', 'n', 'm', 'c', 'd', 'f', 'r', 't', 'u'];
    
    % Insere caracteres e letras errados aleatoriamente nos índices selecionados

    for i = 1:length(indices_insercao)
        if rand < 0.5 % 50% de chance de inserir um caractere especial
            letra_errada = caracteres_errados(randi(length(caracteres_errados)));
        else % 50% de chance de inserir uma letra errada
            letra_errada = letras_erradas(randi(length(letras_erradas)));
        end
        letras = [letras(1:indices_insercao(i)), letra_errada, letras(indices_insercao(i)+1:end)];
    end
    
    % Converte a matriz de caracteres de volta para uma string
    palavra_com_erros(k) = string(letras);
    app.ListBox.Items = palavra_com_erros;
    % Vetor de células de exemplo


 end
 vetor_de_strings = palavra_com_erros;
 % Caminho do arquivo CSV


% Salvar o vetor de células no arquivo CSV
% writecell(vetor_de_strings, caminho_arquivo);
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'RFDataHub';
            app.UIFigure.Icon = 'C:\P&D\SCH\Icons\LR_icon.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.LeftPanelVisibility = uiimage(app.Grid);
            app.LeftPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelVisibilityControl, true);
            app.LeftPanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_HideLeft.png');
            app.Document = uigridlayout(app.Grid);
            app.UITable = uitable(app.Document);
            app.UITable.ColumnName = {'auto'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.DoubleClickedFcn = createCallbackFcn(app, @UITableDoubleClicked, true);
            app.UITable.Multiselect = 'off';
            app.TabGroup2 = uitabgroup(app.Document);
            app.TabGroup2.Tag = '2';
            app.DadosSCHTab = uitab(app.TabGroup2);
            app.DadosSCHTab.Title = 'Dados sobre a Certificação do Produto';
            app.DadosSCHTab.Tag = '1';
            app.GridLayout2 = uigridlayout(app.DadosSCHTab);
            app.CheckBox_CadastroProdutos = uicheckbox(app.GridLayout2);
            app.CheckBox_CadastroProdutos.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CadastroProdutosValueChanged, true);
            app.CheckBox_CadastroProdutos.Text = 'Inserir informações sobre o produto?';
            app.NomedofabricanteEditFieldLabel = uilabel(app.GridLayout2);
            app.NomedofabricanteEditFieldLabel.Text = 'Nome do fabricante:';
            app.NomedofabricanteEditField = uieditfield(app.GridLayout2, 'text');
            app.NomedofabricanteEditField.Editable = 'off';
            app.ModeloEditFieldLabel = uilabel(app.GridLayout2);
            app.ModeloEditFieldLabel.Text = 'Modelo:';
            app.ModeloEditField = uieditfield(app.GridLayout2, 'text');
            app.ModeloEditField.Editable = 'off';
            app.NomeComercialEditFieldLabel = uilabel(app.GridLayout2);
            app.NomeComercialEditFieldLabel.Text = 'Nome Comercial:';
            app.NomeComercialEditField = uieditfield(app.GridLayout2, 'text');
            app.NomeComercialEditField.Editable = 'off';
            app.TipodeprodutoEditFieldLabel = uilabel(app.GridLayout2);
            app.TipodeprodutoEditFieldLabel.Text = 'Tipo de produto:';
            app.TipodeprodutoEditField = uieditfield(app.GridLayout2, 'text');
            app.TipodeprodutoEditField.Editable = 'off';
            app.NmerodehomologaoEditFieldLabel = uilabel(app.GridLayout2);
            app.NmerodehomologaoEditFieldLabel.Text = 'Número de homologação:';
            app.NmerodehomologaoEditField = uieditfield(app.GridLayout2, 'text');
            app.NmerodehomologaoEditField.Editable = 'off';
            app.SituaodorequerimentoEditFieldLabel = uilabel(app.GridLayout2);
            app.SituaodorequerimentoEditFieldLabel.Text = 'Situação do requerimento:';
            app.SituaodorequerimentoEditField = uieditfield(app.GridLayout2, 'text');
            app.SituaodorequerimentoEditField.Editable = 'off';
            app.NomedosolicitanteEditFieldLabel = uilabel(app.GridLayout2);
            app.NomedosolicitanteEditFieldLabel.Text = 'Nome do solicitante:';
            app.NomedosolicitanteEditField = uieditfield(app.GridLayout2, 'text');
            app.NomedosolicitanteEditField.Editable = 'off';
            app.CNPJdosolicitanteEditFieldLabel = uilabel(app.GridLayout2);
            app.CNPJdosolicitanteEditFieldLabel.Text = 'CNPJ do solicitante:';
            app.CNPJdosolicitanteEditField = uieditfield(app.GridLayout2, 'text');
            app.CNPJdosolicitanteEditField.Editable = 'off';
            app.OutrosnomesdoprodutoTextArea_2Label = uilabel(app.GridLayout2);
            app.OutrosnomesdoprodutoTextArea_2Label.Text = 'Outros nomes do produto:';
            app.OutrosnomesdoprodutoTextArea_2 = uitextarea(app.GridLayout2);
            app.ObservaesimportantesTextArea_2Label = uilabel(app.GridLayout2);
            app.ObservaesimportantesTextArea_2Label.Text = 'Observações importantes:';
            app.ObservaesimportantesTextArea_2 = uitextarea(app.GridLayout2);
            app.CertificadodeconformidadeEditFieldLabel = uilabel(app.GridLayout2);
            app.CertificadodeconformidadeEditFieldLabel.Text = 'Certificado de conformidade:';
            app.CertificadodeconformidadeEditField = uieditfield(app.GridLayout2, 'text');
            app.CertificadodeconformidadeEditField.Editable = 'off';
            app.ValidadedocertificadoEditFieldLabel = uilabel(app.GridLayout2);
            app.ValidadedocertificadoEditFieldLabel.Text = 'Validade do certificado:';
            app.ValidadedocertificadoEditField = uieditfield(app.GridLayout2, 'text');
            app.ValidadedocertificadoEditField.Editable = 'off';
            app.SituaodocertificadoEditFieldLabel = uilabel(app.GridLayout2);
            app.SituaodocertificadoEditFieldLabel.Text = 'Situação do certificado:';
            app.SituaodocertificadoEditField = uieditfield(app.GridLayout2, 'text');
            app.SituaodocertificadoEditField.Editable = 'off';
            app.CadastroSCH = uitab(app.TabGroup2);
            app.CadastroSCH.Title = 'Cadastro de informações sobre o produto';
            app.CadastroSCH.Tag = '2';
            app.GridLayout3 = uigridlayout(app.CadastroSCH);
            app.OutrosnomesdoprodutoTextAreaLabel = uilabel(app.GridLayout3);
            app.OutrosnomesdoprodutoTextAreaLabel.Text = 'Outros nomes do produto:';
            app.OutrosnomesdoprodutoTextArea = uitextarea(app.GridLayout3);
            app.ObservaesimportantesTextAreaLabel = uilabel(app.GridLayout3);
            app.ObservaesimportantesTextAreaLabel.Text = 'Observações importantes:';
            app.ObservaesimportantesTextArea = uitextarea(app.GridLayout3);
            app.CadastrardadosButton = uibutton(app.GridLayout3, 'push');
            app.CadastrardadosButton.Text = 'Cadastrar dados';
            app.RetornarButton = uibutton(app.GridLayout3, 'push');
            app.RetornarButton.ButtonPushedFcn = createCallbackFcn(app, @RetornarButtonPushed, true);
            app.RetornarButton.Text = 'Retornar';
            app.toolGrid = uigridlayout(app.Grid);
            app.tool_ExportButton = uibutton(app.toolGrid, 'push');
            app.tool_ExportButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Export_24.png');
            app.tool_ExportButton.Tooltip = {'Exportar tabela'};
            app.tool_ExportButton.Text = '';
            app.tool_Serarator1 = uiimage(app.toolGrid);
            app.tool_Serarator1.Enable = 'off';
            app.tool_Serarator1.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_LineV.png');
            app.tool_HistogramAxesVisibility = uibutton(app.toolGrid, 'state');
            app.tool_HistogramAxesVisibility.Tooltip = {'Eixo cartesiano: Histogramas'};
            app.tool_HistogramAxesVisibility.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_Detection.png');
            app.tool_HistogramAxesVisibility.Text = '';
            app.tool_HistogramAxesVisibility.Value = true;
            app.tool_simulationAxesVisibility = uibutton(app.toolGrid, 'state');
            app.tool_simulationAxesVisibility.Tooltip = {'Eixo cartesiano: Enlace'};
            app.tool_simulationAxesVisibility.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_connect.png');
            app.tool_simulationAxesVisibility.Text = '';
            app.tool_Serarator2 = uiimage(app.toolGrid);
            app.tool_Serarator2.Enable = 'off';
            app.tool_Serarator2.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_LineV.png');
            app.tool_PDFButton = uibutton(app.toolGrid, 'push');
            app.tool_PDFButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Publish_PDF_16-69bd9a64885e6016ee3e1ed74cc2597d.png');
            app.tool_PDFButton.Enable = 'off';
            app.tool_PDFButton.Tooltip = {'Relatório do canal'};
            app.tool_PDFButton.Text = '';
            app.tool_simulationButton = uibutton(app.toolGrid, 'push');
            app.tool_simulationButton.Icon = fullfile(pathToMLAPP, 'Icons', 'Publish_HTML_16-8792a26e8b38d73b8ffe54ffae928360.png');
            app.tool_simulationButton.Enable = 'off';
            app.tool_simulationButton.Tooltip = {'Enlace'};
            app.tool_simulationButton.Text = '';
            app.tool_nRowsGrid = uigridlayout(app.toolGrid);
            app.tool_nRows = uilabel(app.tool_nRowsGrid);
            app.tool_nRows.Text = {'0 de 979522 registros'; '0.0 %'};
            app.tool_nRowsIcon = uiimage(app.tool_nRowsGrid);
            app.tool_nRowsIcon.Enable = 'off';
            app.tool_nRowsIcon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Filter2.png');
            app.tool_resetButton = uiimage(app.tool_nRowsGrid);
            app.tool_resetButton.ImageClickedFcn = createCallbackFcn(app, @resetFcn, true);
            app.tool_resetButton.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Dots.png');
            app.jsBackDoor = uihtml(app.toolGrid);
            app.jsBackDoor.HTMLSource = ' ';
            app.TabGroup = uitabgroup(app.Grid);
            app.Tab = uitab(app.TabGroup);
            app.Tab1_infoGrid = uigridlayout(app.Tab);
            app.layout_Intro = uilabel(app.Tab1_infoGrid);
            app.layout_Intro.Interpreter = 'html';
            app.layout_Intro.Text = '<p style="text-align: justify; line-height: 1; margin-right: 2px;">O <b>RFDataHub</b> é um <i>pipeline</i> de dados de estações de telecomunicações extraídos de bases de dados do MOSAICO, SRD, ICAO, AISWEB, GEOAIS e REDEMET. Os dados aqui apresentados foram consolidados em <b>%s</b>.</p>';
            app.layout_Grid = uigridlayout(app.Tab1_infoGrid);
            app.layout_mapButton = ccTools.Button(app.layout_Grid);
            app.layout_mapButton.Text = 'Mapa';
            app.layout_mapButton.Description = 'Visualização georeferenciada das estações';
            app.layout_mapButton.Icon = 'Instr_Global.png';
            app.layout_mapButton.HorizontalAlign = 'left';
            app.layout_mapButton.VerticalAlign = 'top';
            app.layout_mapButton.borderRadius = '0px';
            app.layout_mapButton.borderPadding = 3;
            app.layout_mapButton.IconWidth = 20;
            app.layout_mapButton.IconHeight = 20;
            app.layout_mapButton.tFontWeight = 'normal';
            app.layout_mapButton.tFontSize = 11;
            app.layout_mapButton.dFontSize = 9;
            app.layout_mapButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_pdfButton = ccTools.Button(app.layout_Grid);
            app.layout_pdfButton.Text = 'Leitor PDF';
            app.layout_pdfButton.Description = 'Relatório do canal: outorga, licenciamento etc';
            app.layout_pdfButton.Icon = 'Publish_PDF_16-69bd9a64885e6016ee3e1ed74cc2597d.png';
            app.layout_pdfButton.HorizontalAlign = 'left';
            app.layout_pdfButton.VerticalAlign = 'top';
            app.layout_pdfButton.borderRadius = '0px';
            app.layout_pdfButton.borderPadding = 3;
            app.layout_pdfButton.tFontWeight = 'normal';
            app.layout_pdfButton.tFontSize = 11;
            app.layout_pdfButton.dFontSize = 9;
            app.layout_pdfButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_histogramButton = ccTools.Button(app.layout_Grid);
            app.layout_histogramButton.Text = 'Eixo cartesiano';
            app.layout_histogramButton.Description = 'Histogramas, enlace';
            app.layout_histogramButton.Icon = 'LT_Channel.png';
            app.layout_histogramButton.HorizontalAlign = 'left';
            app.layout_histogramButton.VerticalAlign = 'top';
            app.layout_histogramButton.borderRadius = '0px';
            app.layout_histogramButton.borderPadding = 3;
            app.layout_histogramButton.IconWidth = 20;
            app.layout_histogramButton.IconHeight = 20;
            app.layout_histogramButton.tFontWeight = 'normal';
            app.layout_histogramButton.tFontSize = 11;
            app.layout_histogramButton.dFontSize = 9;
            app.layout_histogramButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.layout_tableButton = ccTools.Button(app.layout_Grid);
            app.layout_tableButton.Text = 'Tabela';
            app.layout_tableButton.Description = 'Recorte RFDataHub e coluna calculada "Distância"';
            app.layout_tableButton.Icon = 'View_24-e7385a2edb999ea161ca1de14906714b.png';
            app.layout_tableButton.HorizontalAlign = 'left';
            app.layout_tableButton.VerticalAlign = 'top';
            app.layout_tableButton.borderRadius = '0px';
            app.layout_tableButton.borderPadding = 3;
            app.layout_tableButton.tFontWeight = 'normal';
            app.layout_tableButton.tFontSize = 11;
            app.layout_tableButton.dFontSize = 9;
            app.layout_tableButton.ButtonPushedFcn = createCallbackFcn(app, @layout_compButtonPushed, true);
            app.points_TreeLabel = uilabel(app.Tab1_infoGrid);
            app.points_TreeLabel.Interpreter = 'html';
            app.points_TreeLabel.Text = {'<b>Pontos de monitoração:</b>'; '<font style="font-size: 9px";>(referência da coluna calculada "Distância")</font>'};
            app.points_refreshGrid = uigridlayout(app.Tab1_infoGrid);
            app.points_refreshImage = uiimage(app.points_refreshGrid);
            app.points_refreshImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_refresh.png');
            app.points_Tree = uitree(app.Tab1_infoGrid);
            app.points_Panel = uipanel(app.Tab1_infoGrid);
            app.points_Grid = uigridlayout(app.points_Panel);
            app.points_LatitudeLabel = uilabel(app.points_Grid);
            app.points_LatitudeLabel.Text = 'Latitude:';
            app.points_Latitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Latitude.ValueDisplayFormat = '%.6f';
            app.points_Latitude.Editable = 'off';
            app.points_Latitude.Value = -1;
            app.points_LongitudeLabel = uilabel(app.points_Grid);
            app.points_LongitudeLabel.Text = 'Longitude:';
            app.points_Longitude = uieditfield(app.points_Grid, 'numeric');
            app.points_Longitude.ValueDisplayFormat = '%.6f';
            app.points_Longitude.Editable = 'off';
            app.points_Longitude.Value = -1;
            app.points_AntennaHeightLabel = uilabel(app.points_Grid);
            app.points_AntennaHeightLabel.Text = 'Altura (m):';
            app.points_AntennaHeight = uieditfield(app.points_Grid, 'numeric');
            app.points_AntennaHeight.ValueDisplayFormat = '%.6f';
            app.points_AntennaHeight.Editable = 'off';
            app.points_AntennaHeight.Value = -1;
            app.points_editButton = uibutton(app.points_Grid, 'push');
            app.points_editButton.Icon = fullfile(pathToMLAPP, 'Icons', 'LT_edit.png');
            app.points_editButton.Text = '';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab2_infoGrid = uigridlayout(app.Tab_3);
            app.filter_Intro = uilabel(app.Tab2_infoGrid);
            app.filter_Intro.Interpreter = 'html';
            app.filter_Intro.Text = '<p style="text-align: justify; line-height: 1; margin-right: 2px;">Os filtros são orientados à informação da própria base - "Fonte", "Frequência" e "Largura de Banda", por exemplo - e à coluna calculada "Distância" - "Distância ou "ROI". Além disso, os filtros podem ser combinados usando os operadores lógicos E ou OU.</p>';
            app.filter_TypePanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_TypePanel.SelectionChangedFcn = createCallbackFcn(app, @filter_typePanelSelectionChanged, true);
            app.filter_TypeRadioButton1 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton1.Tag = '16';
            app.filter_TypeRadioButton1.Text = 'Fonte';
            app.filter_TypeRadioButton1.Value = true;
            app.filter_TypeRadioButton2 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton2.Tag = '1';
            app.filter_TypeRadioButton2.Text = 'Frequência';
            app.filter_TypeRadioButton3 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton3.Tag = '13';
            app.filter_TypeRadioButton3.Text = 'Largura banda';
            app.filter_TypeRadioButton4 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton4.Tag = '12';
            app.filter_TypeRadioButton4.Text = 'Classe emissão';
            app.filter_TypeRadioButton5 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton5.Tag = '2';
            app.filter_TypeRadioButton5.Text = 'Entidade';
            app.filter_TypeRadioButton6 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton6.Tag = '3';
            app.filter_TypeRadioButton6.Text = 'Fistel';
            app.filter_TypeRadioButton7 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton7.Tag = '4';
            app.filter_TypeRadioButton7.Text = 'Serviço';
            app.filter_TypeRadioButton8 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton8.Tag = '5';
            app.filter_TypeRadioButton8.Text = 'Estação';
            app.filter_TypeRadioButton9 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton9.Tag = '10';
            app.filter_TypeRadioButton9.Text = 'UF';
            app.filter_TypeRadioButton10 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton10.Tag = '9';
            app.filter_TypeRadioButton10.Text = 'Município';
            app.filter_TypeRadioButton11 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton11.Tag = '32';
            app.filter_TypeRadioButton11.Text = 'Distância';
            app.filter_TypeRadioButton12 = uiradiobutton(app.filter_TypePanel);
            app.filter_TypeRadioButton12.Tag = '-1';
            app.filter_TypeRadioButton12.Text = 'ROI';
            app.filter_operationPanel = uibuttongroup(app.Tab2_infoGrid);
            app.filter_operationPanel.SelectionChangedFcn = createCallbackFcn(app, @filter_operationPanelSelectionChanged, true);
            app.filter_operationButton1 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton1.Tag = 'numeric+textFree+textList2';
            app.filter_operationButton1.Tooltip = {'Igual'};
            app.filter_operationButton1.Text = '=';
            app.filter_operationButton2 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton2.Tag = 'numeric+textFree+textList2';
            app.filter_operationButton2.Tooltip = {'Diferente'};
            app.filter_operationButton2.Text = '≠';
            app.filter_operationButton3 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton3.Tag = 'ROI+textFree+textList1';
            app.filter_operationButton3.Tooltip = {'Contém'};
            app.filter_operationButton3.Text = '⊃';
            app.filter_operationButton3.Value = true;
            app.filter_operationButton4 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton4.Tag = 'textFree+textList1';
            app.filter_operationButton4.Tooltip = {'Não contém'};
            app.filter_operationButton4.Text = '⊅';
            app.filter_operationButton5 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton5.Tag = 'numeric';
            app.filter_operationButton5.Tooltip = {'Menor'};
            app.filter_operationButton5.Text = '<';
            app.filter_operationButton6 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton6.Tag = 'numeric';
            app.filter_operationButton6.Tooltip = {'Menor ou igual'};
            app.filter_operationButton6.Text = '≤';
            app.filter_operationButton7 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton7.Tag = 'numeric';
            app.filter_operationButton7.Tooltip = {'Maior'};
            app.filter_operationButton7.Text = '>';
            app.filter_operationButton8 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton8.Tag = 'numeric';
            app.filter_operationButton8.Tooltip = {'Maior ou igual'};
            app.filter_operationButton8.Text = '≥';
            app.filter_operationButton9 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton9.Tag = 'numeric';
            app.filter_operationButton9.Tooltip = {'Dentro do intervalo'};
            app.filter_operationButton9.Text = '><';
            app.filter_operationButton10 = uitogglebutton(app.filter_operationPanel);
            app.filter_operationButton10.Tag = 'numeric';
            app.filter_operationButton10.Tooltip = {'Fora do intervalo'};
            app.filter_operationButton10.Text = '<>';
            app.filter_operationValuesPanel = uipanel(app.filter_operationPanel);
            app.filter_operationValuesGrid = uigridlayout(app.filter_operationValuesPanel);
            app.filter_operationNumValue1 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue1.Limits = [-1 Inf];
            app.filter_operationNumValue1.Value = -1;
            app.filter_operationSeparator = uilabel(app.filter_operationValuesGrid);
            app.filter_operationSeparator.Text = '-';
            app.filter_operationNumValue2 = uieditfield(app.filter_operationValuesGrid, 'numeric');
            app.filter_operationNumValue2.Limits = [-1 Inf];
            app.filter_operationNumValue2.Value = -1;
            app.filter_operationTextFreeValue = uieditfield(app.filter_operationValuesGrid, 'text');
            app.filter_operationTextListValue = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationTextListValue.Items = {};
            app.filter_operationTextListValue.Value = {};
            app.filter_operationLogicalReferenceLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalReferenceLabel.Text = {'Filtro de '; 'referência (ID):'};
            app.filter_operationLogicalReference = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogicalReference.Items = {};
            app.filter_operationLogicalReference.ValueChangedFcn = createCallbackFcn(app, @filter_operationLogicalReferenceValueChanged, true);
            app.filter_operationLogicalReference.Value = {};
            app.filter_operationLogicalLabel = uilabel(app.filter_operationValuesGrid);
            app.filter_operationLogicalLabel.Text = {'Operador '; 'lógico:'};
            app.filter_operationLogical = uidropdown(app.filter_operationValuesGrid);
            app.filter_operationLogical.Items = {'E (&&)'};
            app.filter_operationLogical.Value = 'E (&&)';
            app.filter_addImage = uiimage(app.Tab2_infoGrid);
            app.filter_addImage.ImageClickedFcn = createCallbackFcn(app, @filter_addFilter, true);
            app.filter_addImage.Tooltip = {'Adicionar filtro'};
            app.filter_addImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Add.png');
            app.filter_Tree = uitree(app.Tab2_infoGrid, 'checkbox');
            % Assign Checked Nodes
            app.filter_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @filter_TreeCheckedNodesChanged, true);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_4 = uitab(app.TabGroup);
            app.GridLayout = uigridlayout(app.Tab_4);
            app.misc_Intro_2 = uilabel(app.GridLayout);
            app.misc_Intro_2.Interpreter = 'html';
            app.misc_Intro_2.Text = '<p style="text-align: justify; line-height: 1; margin-right: 2px;">Este webapp possibilita realizar consultas mais eficientes em relação aos sistemas disponiveis do SCH, visando atender as necessidades do fiscal. Além disso permite a inserção de informações complementares conforme observações dos fiscais no que seferem aos equipamentos analisados</p>';
            app.filter_TypePanel_2 = uibuttongroup(app.GridLayout);
            app.filter_TypeRadioButton1_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton1_2.Tag = '16';
            app.filter_TypeRadioButton1_2.Text = 'N º Homologação';
            app.filter_TypeRadioButton1_2.Value = true;
            app.filter_TypeRadioButton2_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton2_2.Tag = '1';
            app.filter_TypeRadioButton2_2.Text = 'Nome do Solicitante';
            app.filter_TypeRadioButton3_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton3_2.Tag = '13';
            app.filter_TypeRadioButton3_2.Text = 'Nome do Fabricante';
            app.filter_TypeRadioButton5_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton5_2.Tag = '2';
            app.filter_TypeRadioButton5_2.Text = 'Data do Certificado';
            app.filter_TypeRadioButton6_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton6_2.Tag = '3';
            app.filter_TypeRadioButton6_2.Text = 'Data Validade do Certificado';
            app.filter_TypeRadioButton7_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton7_2.Tag = '4';
            app.filter_TypeRadioButton7_2.Text = 'Situação do Certificado';
            app.filter_TypeRadioButton8_2 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton8_2.Tag = '5';
            app.filter_TypeRadioButton8_2.Text = 'Tipo do Produto';
            app.filter_TypeRadioButton4_3 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton4_3.Tag = '12';
            app.filter_TypeRadioButton4_3.Text = 'Nome Comercial';
            app.filter_TypeRadioButton8_3 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton8_3.Tag = '5';
            app.filter_TypeRadioButton8_3.Text = 'Palavra chave';
            app.filter_TypeRadioButton4_4 = uiradiobutton(app.filter_TypePanel_2);
            app.filter_TypeRadioButton4_4.Tag = '12';
            app.filter_TypeRadioButton4_4.Text = 'Modelo';
            app.filter_userEntry = uieditfield(app.GridLayout, 'text');
            app.filter_userEntry.ValueChangingFcn = createCallbackFcn(app, @filter_userEntryValueChanging, true);
            app.FiltroButton = uibutton(app.GridLayout, 'push');
            app.FiltroButton.ButtonPushedFcn = createCallbackFcn(app, @FiltroButtonPushed, true);
            app.FiltroButton.Text = 'Filtro';
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.BuscaLevenshteinButton = uibutton(app.GridLayout, 'push');
            app.BuscaLevenshteinButton.ButtonPushedFcn = createCallbackFcn(app, @BuscaLevenshteinButtonPushed, true);
            app.BuscaLevenshteinButton.Text = 'Busca Levenshtein';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.BuscajarowinklerButton = uibutton(app.GridLayout, 'push');
            app.BuscajarowinklerButton.ButtonPushedFcn = createCallbackFcn(app, @BuscajarowinklerButtonPushed, true);
            app.BuscajarowinklerButton.Text = 'Busca jarowinkler';
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.Items = {};
            app.ListBox.DoubleClickedFcn = createCallbackFcn(app, @ListBoxDoubleClicked, true);
            app.ListBox.Value = {};
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_refresh.png');
            app.filter_Method = uibuttongroup(app.GridLayout);
            app.filter_Method.SelectionChangedFcn = createCallbackFcn(app, @filter_MethodSelectionChanged, true);
            app.filter_Method1 = uiradiobutton(app.filter_Method);
            app.filter_Method1.Text = 'contains';
            app.filter_Method1.Value = true;
            app.filter_Method2 = uiradiobutton(app.filter_Method);
            app.filter_Method2.Text = 'Levenshtein';
            app.filter_Method3 = uiradiobutton(app.filter_Method);
            app.filter_Method3.Text = 'Jarowinkler';
            app.AlgoritmoLabel = uilabel(app.GridLayout);
            app.AlgoritmoLabel.Text = 'Algoritmo:';
            app.PalavraaconsultarLabel = uilabel(app.GridLayout);
            app.PalavraaconsultarLabel.Text = 'Palavra a consultar:';
            app.TreinopalavraButton = uibutton(app.GridLayout, 'push');
            app.TreinopalavraButton.ButtonPushedFcn = createCallbackFcn(app, @TreinopalavraButtonPushed, true);
            app.TreinopalavraButton.Text = 'Treino palavra';
            app.menu_MainGrid = uigridlayout(app.Grid);
            app.menu_Image = uiimage(app.menu_MainGrid);
            app.menu_Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_LineH.png');
            app.menu_Button1Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_home2.png');
            app.menu_Button2Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Filtros';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_Filter2.png');
            app.menu_Button3Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Enlace';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LT_connect2.png');
            app.menu_Button4Grid = uigridlayout(app.menu_MainGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'SGCH';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @layout_LeftPanelSelectedTabControl, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', '1387596_18x18.png');
            app.filter_ContextMenu = uicontextmenu(app.UIFigure);
            app.filter_delButton = uimenu(app.filter_ContextMenu);
            app.filter_delButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delButton.Text = 'Excluir';
            app.filter_delAllButton = uimenu(app.filter_ContextMenu);
            app.filter_delAllButton.MenuSelectedFcn = createCallbackFcn(app, @filter_delFilter, true);
            app.filter_delAllButton.Text = 'Excluir todos';
            app.points_ContextMenu = uicontextmenu(app.UIFigure);
            app.points_referenceButton = uimenu(app.points_ContextMenu);
            app.points_referenceButton.Text = 'Referência';
            app.points_editRowButton = uimenu(app.points_ContextMenu);
            app.points_editRowButton.Separator = 'on';
            app.points_editRowButton.Text = 'Editar';
            app.points_delButton = uimenu(app.points_ContextMenu);
            app.points_delButton.Text = 'Excluir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end